classdef GIT_Stuff < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                  matlab.ui.Figure
    GridLayout                matlab.ui.container.GridLayout
    ClearCommandWindowButton  matlab.ui.control.Button
    GITPullButton             matlab.ui.control.Button
    GITPushButton             matlab.ui.control.Button
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: GITPushButton
    function GITPushButtonPushed(app, event)
      userPro = getenv("USERPROFILE");
      git2025 = strcat(userPro,'\GIT25');
      cd(git2025);
      
      res = dir("*2025*");
      
      for ii = 1:length(res)
          pathName = sprintf("%s\\%s", res(ii).folder, res(ii).name);
          disp(" ")
          disp("*************************************")
          disp("PUSHING")
          disp(pathName);
          disp("*************************************")
          cd(pathName);
          dos('git add .');
          dos('git commit -m "Updates"');
          dos('git push --verbose');
      end
      
      cd(git2025);
    end

    % Button pushed function: GITPullButton
    function GITPullButtonPushed(app, event)
      userPro = getenv("USERPROFILE");
      git2025 = strcat(userPro,'\GIT25');
      cd(git2025);
      
      res = dir("*2025*");
      
      for ii = 1:length(res)
          pathName = sprintf("%s\\%s", res(ii).folder, res(ii).name);
          disp(" ")
          disp("*************************************")
          disp("PULLING")
          disp(pathName);
          disp("*************************************")
          cd(pathName);
          dos('git pull --verbose');
      end
      
      cd(git2025);
    end

    % Button pushed function: ClearCommandWindowButton
    function ClearCommandWindowButtonPushed(app, event)
      clc;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.GridLayout = uigridlayout(app.UIFigure);
      app.GITPushButton = uibutton(app.GridLayout, 'push');
      app.GITPushButton.ButtonPushedFcn = createCallbackFcn(app, @GITPushButtonPushed, true);
      app.GITPushButton.Text = 'GITPush';
      app.GITPullButton = uibutton(app.GridLayout, 'push');
      app.GITPullButton.ButtonPushedFcn = createCallbackFcn(app, @GITPullButtonPushed, true);
      app.GITPullButton.Text = 'GITPull';
      app.ClearCommandWindowButton = uibutton(app.GridLayout, 'push');
      app.ClearCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCommandWindowButtonPushed, true);
      app.ClearCommandWindowButton.Text = {'Clear '; 'Command'; 'Window'};
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = GIT_Stuff

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end