classdef lab01 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure       matlab.ui.Figure
    GridLayout     matlab.ui.container.GridLayout
    Switch15       matlab.ui.control.ToggleSwitch
    Switch_6Label  matlab.ui.control.Label
    Switch14       matlab.ui.control.ToggleSwitch
    Switch_5Label  matlab.ui.control.Label
    Switch13       matlab.ui.control.ToggleSwitch
    Switch_4Label  matlab.ui.control.Label
    Switch12       matlab.ui.control.ToggleSwitch
    Switch_3Label  matlab.ui.control.Label
    Switch11       matlab.ui.control.ToggleSwitch
    Switch_2Label  matlab.ui.control.Label
    Switch10       matlab.ui.control.ToggleSwitch
    SwitchLabel    matlab.ui.control.Label
    Switch09       matlab.ui.control.ToggleSwitch
    Label_10       matlab.ui.control.Label
    Switch08       matlab.ui.control.ToggleSwitch
    Label_9        matlab.ui.control.Label
    Switch07       matlab.ui.control.ToggleSwitch
    Label_8        matlab.ui.control.Label
    Switch06       matlab.ui.control.ToggleSwitch
    Label_7        matlab.ui.control.Label
    Switch05       matlab.ui.control.ToggleSwitch
    Label_6        matlab.ui.control.Label
    Switch04       matlab.ui.control.ToggleSwitch
    Label_5        matlab.ui.control.Label
    Switch03       matlab.ui.control.ToggleSwitch
    Label_4        matlab.ui.control.Label
    Switch02       matlab.ui.control.ToggleSwitch
    Label_3        matlab.ui.control.Label
    Switch01       matlab.ui.control.ToggleSwitch
    Label_2        matlab.ui.control.Label
    Switch00       matlab.ui.control.ToggleSwitch
    Label          matlab.ui.control.Label
  end

  
  properties (Access = public)
    lights = zeros(4,4);
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Value changed function: Switch00
    function Switch00ValueChanged(app, event)
      value = app.Switch00.Value;
      if strcmpi(value,'On')
        app.lights(1,1) = 1;
      else
        app.lights(1,1) = 0;
      end
      disp(app.lights);
    end

    % Value changed function: Switch01
    function Switch01ValueChanged(app, event)
      value = app.Switch01.Value;
      if strcmpi(value,'On')
        app.lights(1,2) = 1;
      else
        app.lights(1,2) = 0;
      end
      disp(app.lights);
    end

    % Value changed function: Switch02
    function Switch02ValueChanged(app, event)
      value = app.Switch02.Value;
      if strcmpi(value,'On')
        app.lights(1,3) = 1;
      else
        app.lights(1,3) = 0;
      end
      disp(app.lights);
    end

    % Value changed function: Switch03
    function Switch03ValueChanged(app, event)
      value = app.Switch03.Value;
      if strcmpi(value,'On')
        app.lights(1,4) = 1;
      else
        app.lights(1,4) = 0;
      end
      disp(app.lights);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.GridLayout = uigridlayout(app.UIFigure);
      app.Label = uilabel(app.GridLayout);
      app.Label.Text = '';
      app.Switch00 = uiswitch(app.GridLayout, 'toggle');
      app.Switch00.ValueChangedFcn = createCallbackFcn(app, @Switch00ValueChanged, true);
      app.Label_2 = uilabel(app.GridLayout);
      app.Label_2.Text = '';
      app.Switch01 = uiswitch(app.GridLayout, 'toggle');
      app.Switch01.ValueChangedFcn = createCallbackFcn(app, @Switch01ValueChanged, true);
      app.Label_3 = uilabel(app.GridLayout);
      app.Label_3.Text = '';
      app.Switch02 = uiswitch(app.GridLayout, 'toggle');
      app.Switch02.ValueChangedFcn = createCallbackFcn(app, @Switch02ValueChanged, true);
      app.Label_4 = uilabel(app.GridLayout);
      app.Label_4.Text = '';
      app.Switch03 = uiswitch(app.GridLayout, 'toggle');
      app.Switch03.ValueChangedFcn = createCallbackFcn(app, @Switch03ValueChanged, true);
      app.Label_5 = uilabel(app.GridLayout);
      app.Label_5.Text = '';
      app.Switch04 = uiswitch(app.GridLayout, 'toggle');
      app.Label_6 = uilabel(app.GridLayout);
      app.Label_6.Text = '';
      app.Switch05 = uiswitch(app.GridLayout, 'toggle');
      app.Label_7 = uilabel(app.GridLayout);
      app.Label_7.Text = '';
      app.Switch06 = uiswitch(app.GridLayout, 'toggle');
      app.Label_8 = uilabel(app.GridLayout);
      app.Label_8.Text = '';
      app.Switch07 = uiswitch(app.GridLayout, 'toggle');
      app.Label_9 = uilabel(app.GridLayout);
      app.Label_9.Text = '';
      app.Switch08 = uiswitch(app.GridLayout, 'toggle');
      app.Label_10 = uilabel(app.GridLayout);
      app.Label_10.Text = '';
      app.Switch09 = uiswitch(app.GridLayout, 'toggle');
      app.SwitchLabel = uilabel(app.GridLayout);
      app.SwitchLabel.Text = '';
      app.Switch10 = uiswitch(app.GridLayout, 'toggle');
      app.Switch_2Label = uilabel(app.GridLayout);
      app.Switch_2Label.Text = '';
      app.Switch11 = uiswitch(app.GridLayout, 'toggle');
      app.Switch_3Label = uilabel(app.GridLayout);
      app.Switch_3Label.Text = '';
      app.Switch12 = uiswitch(app.GridLayout, 'toggle');
      app.Switch_4Label = uilabel(app.GridLayout);
      app.Switch_4Label.Text = '';
      app.Switch13 = uiswitch(app.GridLayout, 'toggle');
      app.Switch_5Label = uilabel(app.GridLayout);
      app.Switch_5Label.Text = '';
      app.Switch14 = uiswitch(app.GridLayout, 'toggle');
      app.Switch_6Label = uilabel(app.GridLayout);
      app.Switch_6Label.Text = '';
      app.Switch15 = uiswitch(app.GridLayout, 'toggle');
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = lab01

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end